classdef Concentration_conversion_calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ConvertDropDownLabel      matlab.ui.control.Label
        DropDownInput             matlab.ui.control.DropDown
        toDropDownLabel           matlab.ui.control.Label
        DropDownOutput            matlab.ui.control.DropDown
        ConcentrationconversioncalculatorLabel  matlab.ui.control.Label
        MolarmassofsolutegmolEditFieldLabel  matlab.ui.control.Label
        ValueMolarMassOfSolute    matlab.ui.control.NumericEditField
        ConvertButton             matlab.ui.control.Button
        ValueInputConcentration   matlab.ui.control.EditField
        ValueOutputConcentration  matlab.ui.control.EditField
        Densityofsolutiongcm3EditFieldLabel  matlab.ui.control.Label
        ValueDensity              matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
            
            vI = app.ValueInputConcentration.Value;
            vI = erase(vI,{'[',']'});
            vI = split(vI,{', ',',',' '});
            vI = str2double(vI)';
            app.ValueDensity.Value
            
            vD = app.ValueDensity.Value
            vD = erase(vD,{'[',']'})
            vD = split(vD,{', ',',',' '})
            vD = str2double(vD)';
            
            vO = []; 
            DDIV = app.DropDownInput.Value 
            DDOV = app.DropDownOutput.Value
            
            if strcmp(DDIV,'mol/kg (molal conc.)') && strcmp(DDOV,'mol/L (molar conc.)') 
                vO = vD./(1./vI + app.ValueMolarMassOfSolute.Value/1000)
            end
            
            if strcmp(DDIV,'mol/L (molar conc.)') && strcmp(DDOV,'mol/kg (molal conc.)') 
                vO = 1./(vD./vI - app.ValueMolarMassOfSolute.Value/1000)
            end
            
            if strcmp(DDIV,'mol/L (molar conc.)') && strcmp(DDOV,'kg/kg% (mass fraction)') 
                vO = 1/10*vI*app.ValueMolarMassOfSolute.Value./vD;
            end
            
            if strcmp(DDIV,'kg/kg% (mass fraction)') && strcmp(DDOV,'mol/L (molar conc.)') 
                vO = 10*vI.*vD/app.ValueMolarMassOfSolute.Value;
            end
            
            if strcmp(DDIV,'mol/kg (molal conc.)') && strcmp(DDOV,'kg/kg% (mass fraction)')
                vO = 1./(1000./(vI*app.ValueMolarMassOfSolute.Value) + 1)*100; 
            end
            
            if strcmp(DDIV,'kg/kg% (mass fraction)') && strcmp(DDOV,'mol/kg (molal conc.)') 
                vO = 1000./(app.ValueMolarMassOfSolute.Value*(100./vI + 1));
            end
            
            strOut = []; 
            for i = 1:length(vO)-1
                strOut = [strOut, num2str(vO(i),4), ', '];
            end
            strOut = ['[', strOut, num2str(vO(end),4), ']']
            app.ValueOutputConcentration.Value = strOut;
            
        end

        % Value changed function: DropDownInput
        function DropDownInputValueChanged(app, event)
            set(app.ConvertButton,'Enable','on'); 
            if strcmp(app.DropDownInput.Value,app.DropDownOutput.Value)
                set(app.ConvertButton,'Enable','off'); 
            end
        end

        % Value changed function: DropDownOutput
        function DropDownOutputValueChanged(app, event)
            set(app.ConvertButton,'Enable','on'); 
            if strcmp(app.DropDownInput.Value,app.DropDownOutput.Value)
                set(app.ConvertButton,'Enable','off');
                app.ValueOutputConcentration.Value = ''; 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ConvertDropDownLabel = uilabel(app.UIFigure);
            app.ConvertDropDownLabel.Text = 'Convert';
            app.DropDownInput = uidropdown(app.UIFigure);
            app.DropDownInput.Items = {'mol/L (molar conc.)', 'mol/kg (molal conc.)', 'kg/kg% (mass fraction)'};
            app.DropDownInput.ValueChangedFcn = createCallbackFcn(app, @DropDownInputValueChanged, true);
            app.DropDownInput.Value = 'mol/L (molar conc.)';
            app.toDropDownLabel = uilabel(app.UIFigure);
            app.toDropDownLabel.Text = 'to';
            app.DropDownOutput = uidropdown(app.UIFigure);
            app.DropDownOutput.Items = {'mol/L (molar conc.)', 'mol/kg (molal conc.)', 'kg/kg% (mass fraction)'};
            app.DropDownOutput.ValueChangedFcn = createCallbackFcn(app, @DropDownOutputValueChanged, true);
            app.DropDownOutput.Value = 'mol/L (molar conc.)';
            app.ConcentrationconversioncalculatorLabel = uilabel(app.UIFigure);
            app.ConcentrationconversioncalculatorLabel.Text = 'Concentration conversion calculator';
            app.MolarmassofsolutegmolEditFieldLabel = uilabel(app.UIFigure);
            app.MolarmassofsolutegmolEditFieldLabel.Text = 'Molar mass of solute (g/mol)';
            app.ValueMolarMassOfSolute = uieditfield(app.UIFigure, 'numeric');
            app.ValueMolarMassOfSolute.Value = 74.5513;
            app.ConvertButton = uibutton(app.UIFigure, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Enable = 'off';
            app.ConvertButton.Text = 'Convert!';
            app.ValueInputConcentration = uieditfield(app.UIFigure, 'text');
            app.ValueInputConcentration.Value = '[4.00, 4.50]';
            app.ValueOutputConcentration = uieditfield(app.UIFigure, 'text');
            app.Densityofsolutiongcm3EditFieldLabel = uilabel(app.UIFigure);
            app.Densityofsolutiongcm3EditFieldLabel.Text = 'Density of solution (g/cm^3)';
            app.ValueDensity = uieditfield(app.UIFigure, 'text');
            app.ValueDensity.Value = '[1.15, 1.16]';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Concentration_conversion_calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end